classdef InterfaceSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MassemesuregEditField       matlab.ui.control.NumericEditField
        MassemesuregEditFieldLabel  matlab.ui.control.Label
        RafrachirButton             matlab.ui.control.Button
        EntreMassegEditField        matlab.ui.control.NumericEditField
        EntreMasseKgLabel           matlab.ui.control.Label
        ArrtersimulationButton      matlab.ui.control.Button
        DmarrersimulationButton     matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EcouteurSimulink
        TableauTemps = [] % stock les x
        TableauMasse = [] % stock les y
    end
    
    methods (Access = private)
        
        function TracerGraphique(app)
    nom_modele = 'Simulation_balanceversionajour2024_avec_statespace';
    status = get_param(nom_modele, 'SimulationStatus');
    
    if strcmp(status, 'running')
        try
            % 1. On lit le temps exact de la simulation Simulink
            t = get_param(nom_modele, 'SimulationTime');
            
            % 2. On lit la valeur de ton bloc Espion
            y = evalin('base', 'masse_en_direct');
            
            % 3. On ajoute ces nouveaux points dans notre mémoire
            app.TableauTemps(end+1) = t;
            app.TableauMasse(end+1) = double(y);
            
            % 4. EFFET OSCILLOSCOPE : On garde seulement les 50 derniers points 
            % pour que le graphique avance au lieu de s'écraser
            if length(app.TableauTemps) > 50
                app.TableauTemps(1) = [];
                app.TableauMasse(1) = [];
            end
            
            % 5. On dessine la ligne bleue sur ton interface
            plot(app.UIAxes, app.TableauTemps, app.TableauMasse, 'b-', 'LineWidth', 2);
        catch
            % Si la donnée n'est pas encore prête, on ne fait rien pour éviter un crash
        end
    end
end
        
        function MettreAJourGraphique(app, block)
    % 1. On lit le temps et la donnée qui sortent de notre bloc Gain
    t = block.CurrentTime;
    y = block.OutputPort(1).Data;
    
    % 2. On ajoute ces nouveaux points dans la mémoire
    app.TableauTemps(end+1) = t;
    app.TableauMasse(end+1) = double(y);
    
    % 3. Effet Oscilloscope : On garde seulement les 100 derniers points 
    % pour que la ligne défile de droite à gauche sans écraser le PC
    if length(app.TableauTemps) > 100
        app.TableauTemps(1) = [];
        app.TableauMasse(1) = [];
    end
    
    % 4. On dessine la ligne bleue sur ton UIAxes
    plot(app.UIAxes, app.TableauTemps, app.TableauMasse, 'b-', 'LineWidth', 2);
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrersimulationButton
        function DmarrersimulationButtonPushed(app, event)
% 1. On vide la mémoire pour effacer l'ancien graphique
app.TableauTemps = [];
app.TableauMasse = [];

% 2. On démarre la simulation
nom_modele = 'Simulation_balanceversionajour2024_avec_statespace';
set_param(nom_modele, 'SimulationCommand', 'start');

% 3. Très important : une petite pause de 0.5s pour laisser Simulink s'allumer
pause(0.5); 

% 4. On trouve le bloc et on y branche l'écouteur
chemin_bloc = [nom_modele, 'Simulation_balanceversionajour2024_avec_statespace/SortieGraphique'];
rto = get_param(chemin_bloc, 'RuntimeObject');

if ~isempty(rto)
    % On attache le stéthoscope (l'événement 'PostOutputs') à notre fonction
    app.EcouteurSimulink = add_exec_event_listener(rto, 'PostOutputs', @(block, ~) MettreAJourGraphique(app, block));
else
    uialert(app.UIFigure, 'Le bloc SortieGraphique n''a pas été trouvé.', 'Erreur');
end

        end

        % Button pushed function: ArrtersimulationButton
        function ArrtersimulationButtonPushed(app, event)
% On arrête la simulation
set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'stop');

% On débranche le stéthoscope et on libère la mémoire
if ~isempty(app.EcouteurSimulink)
    delete(app.EcouteurSimulink);
    app.EcouteurSimulink = [];
end
        end

        % Value changed function: EntreMassegEditField
        function EntreMassegEditFieldValueChanged(app, event)
            nouvelle_masse = app.EntreMassegEditField.Value;
            valeur_texte = num2str(nouvelle_masse);
            set_param('Simulation_balanceversionajour2024_avec_statespace/Masse (g)', 'value', valeur_texte);
        end

        % Button pushed function: RafrachirButton
        function RafrachirButtonPushed(app, event)
% Button pushed function: RafrachirButton
    status = get_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationStatus');
    
    if strcmp(status, 'running')
        try
            valeur = evalin('base', 'masse_en_direct');
            app.MassemesuregEditField.Value = double(valeur);
        catch
            uialert(app.UIFigure, 'Le signal n''est pas encore arrivé, attends une seconde.', 'Patience');
        end
    else
        uialert(app.UIFigure, 'La simulation doit être en marche.', 'Erreur');
    end
        end

        % Value changed function: MassemesuregEditField
        function MassemesuregEditFieldValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.DmarrersimulationButton = uibutton(app.UIFigure, 'push');
            app.DmarrersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrersimulationButtonPushed, true);
            app.DmarrersimulationButton.Text = 'Démarrer simulation';
            app.ArrtersimulationButton = uibutton(app.UIFigure, 'push');
            app.ArrtersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ArrtersimulationButtonPushed, true);
            app.ArrtersimulationButton.Text = 'Arrêter simulation';
            app.EntreMasseKgLabel = uilabel(app.UIFigure);
            app.EntreMasseKgLabel.Text = 'Entrée Masse (g) ';
            app.EntreMassegEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntreMassegEditField.ValueChangedFcn = createCallbackFcn(app, @EntreMassegEditFieldValueChanged, true);
            app.RafrachirButton = uibutton(app.UIFigure, 'push');
            app.RafrachirButton.ButtonPushedFcn = createCallbackFcn(app, @RafrachirButtonPushed, true);
            app.RafrachirButton.Text = 'Rafraîchir';
            app.MassemesuregEditFieldLabel = uilabel(app.UIFigure);
            app.MassemesuregEditFieldLabel.Text = 'Masse mesurée (g) ';
            app.MassemesuregEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassemesuregEditField.ValueChangedFcn = createCallbackFcn(app, @MassemesuregEditFieldValueChanged, true);
            app.MassemesuregEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceSimulink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end